classdef VetProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DynamicTrialNameEditFieldLabel  matlab.ui.control.Label
        DynamicTrialNameEditField       matlab.ui.control.EditField
        GaitCyclesPanel                 matlab.ui.container.Panel
        UIAxes3                         matlab.ui.control.UIAxes
        GoodgaitcyclesEditFieldLabel    matlab.ui.control.Label
        GoodgaitcyclesEditField         matlab.ui.control.NumericEditField
        UIAxes3_2                       matlab.ui.control.UIAxes
        UIAxes3_3                       matlab.ui.control.UIAxes
        UIAxes3_4                       matlab.ui.control.UIAxes
        GaitCycleLampLabel              matlab.ui.control.Label
        GaitCycleLamp                   matlab.ui.control.Lamp
        GaitCycleLamp_2Label            matlab.ui.control.Label
        GaitCycleLamp_2                 matlab.ui.control.Lamp
        GaitCycleLamp_3Label            matlab.ui.control.Label
        GaitCycleLamp_3                 matlab.ui.control.Lamp
        RGT_RLELabel                    matlab.ui.control.Label
        RLO_RLSLabel                    matlab.ui.control.Label
        RDS_CentroidLabel               matlab.ui.control.Label
        T1_CentroidLabel                matlab.ui.control.Label
        RGT_RLE_1                       matlab.ui.control.Lamp
        RLO_RLS_1                       matlab.ui.control.Lamp
        RDS_CENTROID_1                  matlab.ui.control.Lamp
        T1_CENTROID_1                   matlab.ui.control.Lamp
        RGT_RLELabel_2                  matlab.ui.control.Label
        RLO_RLSLabel_2                  matlab.ui.control.Label
        RDS_CentroidLabel_2             matlab.ui.control.Label
        T1_CentroidLabel_2              matlab.ui.control.Label
        RGT_RLE_2                       matlab.ui.control.Lamp
        RLO_RLS_2                       matlab.ui.control.Lamp
        RDS_CENTROID_2                  matlab.ui.control.Lamp
        T1_CENTROID_2                   matlab.ui.control.Lamp
        RGT_RLELabel_3                  matlab.ui.control.Label
        RLO_RLSLabel_3                  matlab.ui.control.Label
        RDS_CentroidLabel_3             matlab.ui.control.Label
        T1_CentroidLabel_3              matlab.ui.control.Label
        RGT_RLE_3                       matlab.ui.control.Lamp
        RLO_RLS_3                       matlab.ui.control.Lamp
        RDS_CENTROID_3                  matlab.ui.control.Lamp
        T1_CENTROID_3                   matlab.ui.control.Lamp
        UIAxes3_5                       matlab.ui.control.UIAxes
        RGT_RLELabel_4                  matlab.ui.control.Label
        RLO_RLSLabel_4                  matlab.ui.control.Label
        RDS_CentroidLabel_4             matlab.ui.control.Label
        T1_CentroidLabel_4              matlab.ui.control.Label
        RGT_RLE_4                       matlab.ui.control.Lamp
        RLO_RLS_4                       matlab.ui.control.Lamp
        RDS_CENTROID_4                  matlab.ui.control.Lamp
        T1_CENTROID_4                   matlab.ui.control.Lamp
        GaitCycleLamp_4Label            matlab.ui.control.Label
        GaitCycleLamp_4                 matlab.ui.control.Lamp
        StartButton                     matlab.ui.control.Button
        LandmarkDropDownLabel           matlab.ui.control.Label
        LandmarkDropDown                matlab.ui.control.DropDown
        StaticTrialNameEditFieldLabel   matlab.ui.control.Label
        StaticTrialNameEditField        matlab.ui.control.EditField
        PermittableErrorFramesEditFieldLabel  matlab.ui.control.Label
        PermittableErrorFramesEditField  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        dynamic_trial;
        static_trial;
        frame_errors;
        error_percent;
        landmark_coord;
        cycle_time;
        trimmed_coord;
        gait_cycle_locations;
        graphs;
        segment_error_checks;
        good_cycle_count = 0;
        gait_cycle_count = 0;
        
    end
    
    methods (Access = private)       
        
        function results = display_file_not_found(app)
            f = errordlg('Unable to open the specified spreadsheet(s). The file may not exist or is not within the current working directory.','File Error');
            results = "";
        end
        
        function results = plot_cycles(app)     
        
        app.graphs = [app.UIAxes3_2 app.UIAxes3_3 app.UIAxes3_4];
        
            plot(app.UIAxes3,app.cycle_time,app.landmark_coord);
            
            app.gait_cycle_count = length(app.gait_cycle_locations);
            gait_cycle_count = length(app.gait_cycle_locations);
            
            for n = 1:gait_cycle_count
                s_frame(n) = app.gait_cycle_locations(n).start_frame;
                e_frame(n) = app.gait_cycle_locations(n).end_frame;
                segment_checks(n) = app.gait_cycle_locations(n).seg_checks;
                
                is_good(n) = segment_checks(n).RGT_RLE;
                
                if(is_good(n)== 1)
                    app.good_cycle_count = app.good_cycle_count + 1;
                end
            end
            
            app.segment_error_checks = segment_checks;
            
            app.GoodgaitcyclesEditField.Value = app.good_cycle_count;
            
            if(is_good(1))
                plot(app.UIAxes3_2,app.trimmed_coord(s_frame(1):e_frame(1)))   
                app.GaitCycleLamp.Color='green';
            else
                plot(app.UIAxes3_2,app.trimmed_coord(s_frame(1):e_frame(1)))
                app.GaitCycleLamp.Color='red';
            end       
           
            if(gait_cycle_count >= 2 && is_good(2))
                app.UIAxes3_3.Visible='on';
                plot(app.UIAxes3_3,app.trimmed_coord(s_frame(2):e_frame(2)));
                app.GaitCycleLamp_2.Color='green';
            else
                if(gait_cycle_count < 2)
                    plot(app.UIAxes3_3,0,0);
                    app.UIAxes3_3.Visible='off';
                    app.GaitCycleLamp_2.Color='white';
                else
                    app.UIAxes3_3.Visible='on';
                    plot(app.UIAxes3_3,app.trimmed_coord(s_frame(2):e_frame(2)));
                    app.GaitCycleLamp_2.Color='red';
                end
            end
            
            if(gait_cycle_count >= 3 && is_good(3))
                app.UIAxes3_4.Visible='on';
                plot(app.UIAxes3_4,app.trimmed_coord(s_frame(3):e_frame(3)));
                app.GaitCycleLamp_3.Enable='on';
                app.GaitCycleLamp_3.Color='green';
            else
                if(gait_cycle_count < 3)
                    app.UIAxes3_4.Visible='off';
                    plot(app.UIAxes3_4,0,0);
                    app.GaitCycleLamp_3.Color='white';
                else
                    app.UIAxes3_4.Visible='on';
                    plot(app.UIAxes3_4,app.trimmed_coord(s_frame(3):e_frame(3)));
                    app.GaitCycleLamp_3.Color='red';
                end
            end
            
            if(gait_cycle_count >= 4 && is_good(4))
                app.UIAxes3_4.Visible='on';
                plot(app.UIAxes3_5,app.trimmed_coord(s_frame(4):e_frame(4)));
                app.GaitCycleLamp_4.Enable='on';
                app.GaitCycleLamp_4.Color='green';
            else
                if(gait_cycle_count < 4)
                    app.UIAxes3_5.Visible='off';
                    plot(app.UIAxes3_5,0,0);
                    app.GaitCycleLamp_4.Color='white';
                else
                    app.UIAxes3_5.Visible='on';
                    plot(app.UIAxes3_5,app.trimmed_coord(s_frame(4):e_frame(4)));
                    app.GaitCycleLamp_4.Color='red';
                end
            end
            
            results = "";
            
            light_error_lamps(app);
            
        end %function plot_cycles
     
    
    function results = light_error_lamps(app)
    
        s = app.segment_error_checks;
       
        
        if(s(1).RGT_RLE)
            app.RGT_RLE_1.Color = 'green';
        else
            app.RGT_RLE_1.Color = 'red';
        end
        
        if(s(1).RLO_RLS)
            app.RLO_RLS_1.Color = 'green';
        else
            app.RLO_RLS_1.Color = 'red';
        end
        
        if(s(1).RDS_Centroid)
            app.RDS_CENTROID_1.Color = 'green';
        else
            app.RDS_CENTROID_1.Color = 'red';
        end
        
        if(s(1).T1_Centroid)
            app.T1_CENTROID_1.Color = 'green';
        else
            app.T1_CENTROID_1.Color = 'red';
        end
       
        
        if(app.gait_cycle_count >= 2 && s(2).RGT_RLE)
            app.RGT_RLE_2.Color = 'green';
        else
            if(app.gait_cycle_count < 2)
                app.RGT_RLE_2.Color = 'white';
                
            else
              app.RGT_RLE_2.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 2 && s(2).RLO_RLS)
            app.RLO_RLS_2.Color = 'green';
        else
            if(app.gait_cycle_count < 2)
                app.RLO_RLS_2.Color = 'white';
            else
              app.RLO_RLS_2.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 2 && s(2).RDS_Centroid)
            app.RDS_CENTROID_2.Color = 'green';
        else
            if(app.gait_cycle_count < 2)
                app.RDS_CENTROID_2.Color = 'white';
            else
                app.RDS_CENTROID_2.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 2 && s(2).T1_Centroid)
            app.T1_CENTROID_2.Color = 'green';
        else
            if(app.gait_cycle_count < 2)
                app.T1_CENTROID_2.Color = 'white';
            else
                app.T1_CENTROID_2.Color = 'red';
            end
        end
        
        
        if(app.gait_cycle_count >= 3 && s(3).RGT_RLE)
            app.RGT_RLE_3.Color = 'green';
        else
            if(app.gait_cycle_count < 3)
                app.RGT_RLE_3.Color = 'white';
            else
                app.RGT_RLE_3.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 3 && s(3).RLO_RLS)
            app.RLO_RLS_3.Color = 'green';
        else
            if(app.gait_cycle_count < 3)
                app.RLO_RLS_3.Color = 'white';
            else
                app.RLO_RLS_3.Color = 'red';
            end      
        end
        
        if(app.gait_cycle_count >= 3 && s(3).RDS_Centroid)
            app.RDS_CENTROID_3.Color = 'green';
        else
            if(app.gait_cycle_count < 3)
                app.RDS_CENTROID_3.Color = 'white';
            else
                app.RDS_CENTROID_3.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 3 && s(3).T1_Centroid)
            app.T1_CENTROID_3.Color = 'green';
        else        
                if(app.gait_cycle_count < 3)
                   app.T1_CENTROID_3.Color = 'white';
                else
                   app.T1_CENTROID_3.Color = 'red';
                end     
        end
        
        if(app.gait_cycle_count >= 4 && s(4).RGT_RLE)
            app.RGT_RLE_4.Color = 'green';
        else
            if(app.gait_cycle_count < 4)
                app.RGT_RLE_4.Color = 'white';
            else
                app.RGT_RLE_4.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 4 && s(4).RLO_RLS)
            app.RLO_RLS_4.Color = 'green';
        else
            if(app.gait_cycle_count < 4)
                app.RLO_RLS_4.Color = 'white';
            else
                app.RLO_RLS_4.Color = 'red';
            end      
        end
        
        if(app.gait_cycle_count >= 4 && s(4).RDS_Centroid)
            app.RDS_CENTROID_4.Color = 'green';
        else
            if(app.gait_cycle_count < 4)
                app.RDS_CENTROID_4.Color = 'white';
            else
                app.RDS_CENTROID_4.Color = 'red';
            end
        end
        
        if(app.gait_cycle_count >= 4 && s(4).T1_Centroid)
            app.T1_CENTROID_4.Color = 'green';
        else        
                if(app.gait_cycle_count < 4)
                   app.T1_CENTROID_4.Color = 'white';
                else
                   app.T1_CENTROID_4.Color = 'red';
                end     
        end
        
        
        results = "";
         
    end %function light_error_lamps
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: DynamicTrialNameEditField, 
        % GaitCyclesPanel
        function GaitCycleSpreadsheetNameEditFieldValueChanged(app, event)
            app.dynamic_trial = "Trials/" + app.DynamicTrialNameEditField.Value + ".xls";
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
%             try
               
                app.error_percent = str2double(app.error_percent) / 100;              
                app.frame_errors = str2double(app.frame_errors);
                   [app.landmark_coord, app.trimmed_coord, app.cycle_time,app.gait_cycle_locations] = main(app.dynamic_trial,app.static_trial,app.frame_errors,app.error_percent);
%             catch ME
%                 disp(ME)
%                     display_file_not_found(app);
%             end


app.good_cycle_count = 0;
plot_cycles(app);

app.PermittableErrorFramesEditField.Value = "";


        end

        % Value changed function: StaticTrialNameEditField
        function StaticTrialNameEditFieldValueChanged(app, event)
            app.static_trial = "Trials/" + app.StaticTrialNameEditField.Value + ".xls";      
        end

        % Value changed function: PermittableErrorFramesEditField
        function PermittableErrorFramesEditFieldValueChanged(app, event)
            app.frame_errors = app.PermittableErrorFramesEditField.Value;         
        end

        % Callback function
        function PermittableSkinErrorEditFieldValueChanged(app, event)
            app.error_percent = app.PermittableSkinErrorEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DynamicTrialNameEditFieldLabel = uilabel(app.UIFigure);
            app.DynamicTrialNameEditFieldLabel.Text = 'Dynamic Trial Name:';
            app.DynamicTrialNameEditField = uieditfield(app.UIFigure, 'text');
            app.DynamicTrialNameEditField.ValueChangedFcn = createCallbackFcn(app, @GaitCycleSpreadsheetNameEditFieldValueChanged, true);
            app.DynamicTrialNameEditField.Value = 'Ruger No Vest 0';
            app.GaitCyclesPanel = uipanel(app.UIFigure);
            app.GaitCyclesPanel.Title = 'Gait Cycles';
            app.GaitCyclesPanel.SizeChangedFcn = createCallbackFcn(app, @GaitCycleSpreadsheetNameEditFieldValueChanged, true);
            app.UIAxes3 = uiaxes(app.GaitCyclesPanel);
            title(app.UIAxes3, 'Z-coords of landmark')
            xlabel(app.UIAxes3, 'time (s)')
            ylabel(app.UIAxes3, 'position (mm)')
            app.GoodgaitcyclesEditFieldLabel = uilabel(app.GaitCyclesPanel);
            app.GoodgaitcyclesEditFieldLabel.Text = 'Good gait cycles:';
            app.GoodgaitcyclesEditField = uieditfield(app.GaitCyclesPanel, 'numeric');
            app.GoodgaitcyclesEditField.Editable = 'off';
            app.UIAxes3_2 = uiaxes(app.GaitCyclesPanel);
            title(app.UIAxes3_2, 'Gait Cycle 1')
            xlabel(app.UIAxes3_2, 'frame (#)')
            ylabel(app.UIAxes3_2, 'position (mm)')
            app.UIAxes3_3 = uiaxes(app.GaitCyclesPanel);
            title(app.UIAxes3_3, 'Gait Cycle 2')
            xlabel(app.UIAxes3_3, 'frame (#)')
            ylabel(app.UIAxes3_3, '')
            app.UIAxes3_4 = uiaxes(app.GaitCyclesPanel);
            title(app.UIAxes3_4, 'Gait Cycle 3')
            xlabel(app.UIAxes3_4, 'frame (#)')
            ylabel(app.UIAxes3_4, '')
            app.GaitCycleLampLabel = uilabel(app.GaitCyclesPanel);
            app.GaitCycleLampLabel.Text = 'Gait Cycle';
            app.GaitCycleLamp = uilamp(app.GaitCyclesPanel);
            app.GaitCycleLamp_2Label = uilabel(app.GaitCyclesPanel);
            app.GaitCycleLamp_2Label.Text = 'Gait Cycle';
            app.GaitCycleLamp_2 = uilamp(app.GaitCyclesPanel);
            app.GaitCycleLamp_3Label = uilabel(app.GaitCyclesPanel);
            app.GaitCycleLamp_3Label.Text = 'Gait Cycle';
            app.GaitCycleLamp_3 = uilamp(app.GaitCyclesPanel);
            app.RGT_RLELabel = uilabel(app.GaitCyclesPanel);
            app.RGT_RLELabel.Text = 'RGT_RLE:';
            app.RLO_RLSLabel = uilabel(app.GaitCyclesPanel);
            app.RLO_RLSLabel.Text = 'RLO_RLS:';
            app.RDS_CentroidLabel = uilabel(app.GaitCyclesPanel);
            app.RDS_CentroidLabel.Text = 'RDS_Centroid:';
            app.T1_CentroidLabel = uilabel(app.GaitCyclesPanel);
            app.T1_CentroidLabel.Text = 'T1_Centroid:';
            app.RGT_RLE_1 = uilamp(app.GaitCyclesPanel);
            app.RLO_RLS_1 = uilamp(app.GaitCyclesPanel);
            app.RDS_CENTROID_1 = uilamp(app.GaitCyclesPanel);
            app.T1_CENTROID_1 = uilamp(app.GaitCyclesPanel);
            app.RGT_RLELabel_2 = uilabel(app.GaitCyclesPanel);
            app.RGT_RLELabel_2.Text = 'RGT_RLE:';
            app.RLO_RLSLabel_2 = uilabel(app.GaitCyclesPanel);
            app.RLO_RLSLabel_2.Text = 'RLO_RLS:';
            app.RDS_CentroidLabel_2 = uilabel(app.GaitCyclesPanel);
            app.RDS_CentroidLabel_2.Text = 'RDS_Centroid:';
            app.T1_CentroidLabel_2 = uilabel(app.GaitCyclesPanel);
            app.T1_CentroidLabel_2.Text = 'T1_Centroid:';
            app.RGT_RLE_2 = uilamp(app.GaitCyclesPanel);
            app.RLO_RLS_2 = uilamp(app.GaitCyclesPanel);
            app.RDS_CENTROID_2 = uilamp(app.GaitCyclesPanel);
            app.T1_CENTROID_2 = uilamp(app.GaitCyclesPanel);
            app.RGT_RLELabel_3 = uilabel(app.GaitCyclesPanel);
            app.RGT_RLELabel_3.Text = 'RGT_RLE:';
            app.RLO_RLSLabel_3 = uilabel(app.GaitCyclesPanel);
            app.RLO_RLSLabel_3.Text = 'RLO_RLS:';
            app.RDS_CentroidLabel_3 = uilabel(app.GaitCyclesPanel);
            app.RDS_CentroidLabel_3.Text = 'RDS_Centroid:';
            app.T1_CentroidLabel_3 = uilabel(app.GaitCyclesPanel);
            app.T1_CentroidLabel_3.Text = 'T1_Centroid:';
            app.RGT_RLE_3 = uilamp(app.GaitCyclesPanel);
            app.RLO_RLS_3 = uilamp(app.GaitCyclesPanel);
            app.RDS_CENTROID_3 = uilamp(app.GaitCyclesPanel);
            app.T1_CENTROID_3 = uilamp(app.GaitCyclesPanel);
            app.UIAxes3_5 = uiaxes(app.GaitCyclesPanel);
            title(app.UIAxes3_5, 'Gait Cycle 4')
            xlabel(app.UIAxes3_5, 'frame (#)')
            ylabel(app.UIAxes3_5, '')
            app.RGT_RLELabel_4 = uilabel(app.GaitCyclesPanel);
            app.RGT_RLELabel_4.Text = 'RGT_RLE:';
            app.RLO_RLSLabel_4 = uilabel(app.GaitCyclesPanel);
            app.RLO_RLSLabel_4.Text = 'RLO_RLS:';
            app.RDS_CentroidLabel_4 = uilabel(app.GaitCyclesPanel);
            app.RDS_CentroidLabel_4.Text = 'RDS_Centroid:';
            app.T1_CentroidLabel_4 = uilabel(app.GaitCyclesPanel);
            app.T1_CentroidLabel_4.Text = 'T1_Centroid:';
            app.RGT_RLE_4 = uilamp(app.GaitCyclesPanel);
            app.RLO_RLS_4 = uilamp(app.GaitCyclesPanel);
            app.RDS_CENTROID_4 = uilamp(app.GaitCyclesPanel);
            app.T1_CENTROID_4 = uilamp(app.GaitCyclesPanel);
            app.GaitCycleLamp_4Label = uilabel(app.GaitCyclesPanel);
            app.GaitCycleLamp_4Label.Text = 'Gait Cycle';
            app.GaitCycleLamp_4 = uilamp(app.GaitCyclesPanel);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LandmarkDropDownLabel = uilabel(app.UIFigure);
            app.LandmarkDropDownLabel.Text = 'Landmark';
            app.LandmarkDropDown = uidropdown(app.UIFigure);
            app.LandmarkDropDown.Items = {'Right 5th Metacarpal'};
            app.LandmarkDropDown.Value = 'Right 5th Metacarpal';
            app.StaticTrialNameEditFieldLabel = uilabel(app.UIFigure);
            app.StaticTrialNameEditFieldLabel.Text = 'Static Trial Name:';
            app.StaticTrialNameEditField = uieditfield(app.UIFigure, 'text');
            app.StaticTrialNameEditField.ValueChangedFcn = createCallbackFcn(app, @StaticTrialNameEditFieldValueChanged, true);
            app.StaticTrialNameEditField.Value = 'Ruger Cal 0';
            app.PermittableErrorFramesEditFieldLabel = uilabel(app.UIFigure);
            app.PermittableErrorFramesEditFieldLabel.Text = 'Permittable Error Frames';
            app.PermittableErrorFramesEditField = uieditfield(app.UIFigure, 'text');
            app.PermittableErrorFramesEditField.ValueChangedFcn = createCallbackFcn(app, @PermittableErrorFramesEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VetProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end